classdef wp_publisher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WordPressPublisher        matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        PublishpostTab            matlab.ui.container.Tab
        EnableDownLoadCheckBox    matlab.ui.control.CheckBox
        PublishdraftButton        matlab.ui.control.Button
        AppLabel                  matlab.ui.control.Label
        SelectLiveScriptDropDown  matlab.ui.control.DropDown
        SelectLiveScript          matlab.ui.control.Label
        SettingsTab               matlab.ui.container.Tab
        OutputpathLabel           matlab.ui.control.Label
        PathField                 matlab.ui.control.EditField
        WpAuthButtonGroup         matlab.ui.container.ButtonGroup
        BasicButton               matlab.ui.control.RadioButton
        JwtButton                 matlab.ui.control.RadioButton
        BlogInfoActionLabel       matlab.ui.control.Label
        WordPresspasswordLabel    matlab.ui.control.Label
        PasswordField             matlab.ui.control.EditField
        WordPressusernameLabel    matlab.ui.control.Label
        UsernameField             matlab.ui.control.EditField
        WordPressURLLabel         matlab.ui.control.Label
        BlogSiteEditField         matlab.ui.control.EditField
        SaveButton                matlab.ui.control.Button
        ErrorLabel                matlab.ui.control.Label
    end

    % Copyright 2021 - 2021 The MathWorks, Inc.
    properties (Access = private)
        Property % Description
    end

    methods (Access = private)
    
        % Private functions are found in the +wpfunc directory

    end

    methods(Static)

        function [appDir] = getAppDirectory()
            strPath = which('wp_publisher');
            [appDir,~,~] = fileparts(strPath);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appDir = wp_publisher.getAppDirectory();
            if isfile(appDir + "bloginfo.mat")
                wpfunc.loadSavedSiteInfo(app, appDir);
                app.BlogInfoActionLabel.Text = "Update your WordPress blog information";
            else
                app.TabGroup.SelectedTab = app.SettingsTab;
                app.BlogInfoActionLabel.Text = "Input your WordPress blog information below";
            end

            if nargin < 2
                file = "all";
            else
                if ~contains(filename, ".mlx")
                    file = filename + ".mlx";
                else
                    file = filename;
                end
            end

            wpfunc.mlxDropdown(app, file);
        end

        % Button pushed function: SaveButton
        function submitBlogInfo(app, event)
            appDir = wp_publisher.getAppDirectory();
            env = 'prod';

            if (isempty(app.BlogSiteEditField.Value))
                warning 'Site info is empty'
            elseif (isempty(app.UsernameField.Value))
                warning 'Username is empty'
            elseif (isempty(app.PasswordField.Value))
                warning 'Password is empty'
            else
                site     = app.BlogSiteEditField.Value;
                username = app.UsernameField.Value;
                password = app.PasswordField.Value;
                toolpath = app.PathField.Value;
                if app.JwtButton.Value
                    auth = 1;
                    if site(end) == "/"
                        tokenEndPoint = string(site) + 'wp-json/jwt-auth/v1/token';
                    else
                        tokenEndPoint = string(site) + '/wp-json/jwt-auth/v1/token';
                    end
                    tokenRequestCmd = sprintf('curl -H "Accept: application/json" -s --location --request POST "%s?username=%s&password=%s"', tokenEndPoint, username, password);
                    [~, output] = wpfunc.clientRequest(app, tokenRequestCmd, env);

                    if isempty(output)
                        fprintf('The server has returned an invalid response. Please ensure the URL for your blog is accurate \n');
                    else
                        result = jsondecode(output);
                        if isfield(result, 'token')
                            fullpath = string(appDir) + "bloginfo.mat";
                            token = result.token;
                            save(fullpath, "site", "token", "username", "password", "auth", "toolpath");
                            app.BlogInfoActionLabel.Text = "Your blog info is saved";
                        else
                            resData = result.data.status;
                            fprintf('Error Code: %d \nDetails: %s', resData, result.message);
                        end
                    end
                    
                elseif app.BasicButton.Value
                    auth = 2;
                    pdstring = sprintf("%s:%s", username, password);
                    token = matlab.net.base64encode(pdstring);
                    fullpath = string(appDir) + "bloginfo.mat";
                    save(fullpath, "site", "token", "username", "password", "auth", "toolpath");
                    app.BlogInfoActionLabel.Text = "Your blog info is saved";
                end
            end
        end

        % Value changed function: SelectLiveScriptDropDown
        function ScriptSelector(app, event)
            value = app.SelectLiveScriptDropDown.Value;
            mlxname = erase(value, ".mlx");
            customizedPath = wpfunc.checkPath();
            newpath = customizedPath + mlxname;
            if exist(newpath, 'dir') && isfile(newpath + "/post_info.mat")
                load(fullfile(newpath,"/post_info.mat"));
                app.AppLabel.Text = "Edit existing blog article";
                delete(newpath + "/*.html");
            else
                app.AppLabel.Text = "Post new blog article";
            end
        end

        % Button pushed function: PublishdraftButton
        function PublishdraftButtonPushed(app, event)
            currentpath = pwd;
            wpfunc.mlxTohtml(app);
            appDir = wp_publisher.getAppDirectory();
            if isfile(appDir + "bloginfo.mat")
                load(appDir + "bloginfo.mat", 'site', 'token', 'auth');
                if auth == 1
                    authway = "Bearer";
                elseif auth == 2
                    authway = "Basic";
                end
                if ~isempty(token) && ~isempty(site)
                    mlx = app.SelectLiveScriptDropDown.Value;
                    mlxname = erase(mlx, ".mlx");
                    mlxhtml = string(dir(mlxname + "*.html").name);
                    loc = site;
                    env = "prod";
                    wpfunc.delOldImages(app, loc, authway, token, env);
                    wpfunc.delOldMlx(app, currentpath, loc, authway, token, env);
                    wpfunc.removeMlxTitle(mlxhtml);
                    wpfunc.getAllLatex("article_body.html");
                    wpfunc.getAllEquation(app, "article_body.html");
                    wpfunc.getImgToFile(app, mlxname, "article_body.html", string(loc));
                    wpfunc.sendImgToWp(app, loc, authway, token, env);
                    wpfunc.sendMlxToWp(app, currentpath, loc, authway, token, env);
                    wpfunc.replaceEquation(app, mlxhtml, "article_body.html");

                    title = wpfunc.getPostTitle(app, mlxhtml);

                    wpfunc.postArticle(app, title, loc, authway, token);
                    cd(currentpath);
                    app.AppLabel.Text = "Draft is submitted to your WordPress";
                else
                    fprintf('Sorry, your blog information is incomplete \n');
                    app.ErrorLabel.Text = "Sorry, your blog information is incomplete";
                    app.AppLabel.Text = "Draft cannot be posted, see error message below";
                end
            else
                fprintf('Sorry, there is no blog info in your workspace \n');
                app.ErrorLabel.Text = "Sorry, there is no blog info in your workspace";
                app.TabGroup.SelectedTab = app.SettingsTab;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WordPressPublisher = uifigure('Visible', 'off');
            app.WordPressPublisher.Name = 'MATLAB App';
            app.ErrorLabel = uilabel(app.WordPressPublisher);
            app.ErrorLabel.Text = '';
            app.TabGroup = uitabgroup(app.WordPressPublisher);
            app.PublishpostTab = uitab(app.TabGroup);
            app.PublishpostTab.Title = 'Publish post';
            app.SelectLiveScript = uilabel(app.PublishpostTab);
            app.SelectLiveScript.Text = 'Select Live Script';
            app.SelectLiveScriptDropDown = uidropdown(app.PublishpostTab);
            app.SelectLiveScriptDropDown.Items = {};
            app.SelectLiveScriptDropDown.ValueChangedFcn = createCallbackFcn(app, @ScriptSelector, true);
            app.SelectLiveScriptDropDown.Value = {};
            app.AppLabel = uilabel(app.PublishpostTab);
            app.AppLabel.Text = '';
            app.PublishdraftButton = uibutton(app.PublishpostTab, 'push');
            app.PublishdraftButton.ButtonPushedFcn = createCallbackFcn(app, @PublishdraftButtonPushed, true);
            app.PublishdraftButton.Text = 'Publish draft';
            app.EnableDownLoadCheckBox = uicheckbox(app.PublishpostTab);
            app.EnableDownLoadCheckBox.Text = 'Allow your readers to download your source Live Script';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SaveButton = uibutton(app.SettingsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @submitBlogInfo, true);
            app.SaveButton.Text = 'Save';
            app.BlogSiteEditField = uieditfield(app.SettingsTab, 'text');
            app.BlogSiteEditField.Placeholder = 'https://www.myblog.com/';
            app.WordPressURLLabel = uilabel(app.SettingsTab);
            app.WordPressURLLabel.Text = 'WordPress URL';
            app.UsernameField = uieditfield(app.SettingsTab, 'text');
            app.UsernameField.Placeholder = 'username';
            app.WordPressusernameLabel = uilabel(app.SettingsTab);
            app.WordPressusernameLabel.Text = 'WordPress username';
            app.PasswordField = uieditfield(app.SettingsTab, 'text');
            app.PasswordField.Placeholder = 'password';
            app.WordPresspasswordLabel = uilabel(app.SettingsTab);
            app.WordPresspasswordLabel.Text = 'WordPress password';
            app.BlogInfoActionLabel = uilabel(app.SettingsTab);
            app.BlogInfoActionLabel.Text = '';
            app.WpAuthButtonGroup = uibuttongroup(app.SettingsTab);
            app.WpAuthButtonGroup.Title = 'WordPress API authentication ';
            app.JwtButton = uiradiobutton(app.WpAuthButtonGroup);
            app.JwtButton.Text = 'JWT Authentication';
            app.JwtButton.Value = true;
            app.BasicButton = uiradiobutton(app.WpAuthButtonGroup);
            app.BasicButton.Text = 'WP-API Basic-Auth';
            app.PathField = uieditfield(app.SettingsTab, 'text');
            app.PathField.Placeholder = 'Absolute path';
            app.OutputpathLabel = uilabel(app.SettingsTab);
            app.OutputpathLabel.Text = 'Output path (optional)';
            % Show the figure after all components are created
            app.WordPressPublisher.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wp_publisher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WordPressPublisher)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WordPressPublisher)
        end
    end
end